classdef DoAn_THPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NhmTab                         matlab.ui.container.Tab
        TextArea_2                     matlab.ui.control.TextArea
        TextArea                       matlab.ui.control.TextArea
        ThnhvinTab                     matlab.ui.container.Tab
        TextArea_8                     matlab.ui.control.TextArea
        TextArea_7                     matlab.ui.control.TextArea
        TextArea_6                     matlab.ui.control.TextArea
        TextArea_5                     matlab.ui.control.TextArea
        TextArea_4                     matlab.ui.control.TextArea
        TextArea_3                     matlab.ui.control.TextArea
        NhimvTab                       matlab.ui.container.Tab
        Tree                           matlab.ui.container.Tree
        TmnghimNode                    matlab.ui.container.TreeNode
        ChiaiNode                      matlab.ui.container.TreeNode
        LpNode                         matlab.ui.container.TreeNode
        NewtonNode                     matlab.ui.container.TreeNode
        HiquyNode                      matlab.ui.container.TreeNode
        TuyntnhNode                    matlab.ui.container.TreeNode
        HmmNode                        matlab.ui.container.TreeNode
        MeNode                         matlab.ui.container.TreeNode
        NisuyNode                      matlab.ui.container.TreeNode
        NisuyNewtonNode                matlab.ui.container.TreeNode
        NisuyLagrangeNode              matlab.ui.container.TreeNode
        ohmNode                        matlab.ui.container.TreeNode
        ohmtdliuxyNode                 matlab.ui.container.TreeNode
        ohmthmsNode                    matlab.ui.container.TreeNode
        ThayicphngphptnhohmXpxtinxpxlixpxtrungtmNode  matlab.ui.container.TreeNode
        TchphnNode                     matlab.ui.container.TreeNode
        TnhctchphnhnhthangtxyNode      matlab.ui.container.TreeNode
        TnhctchphnhnhthangthmsnhpvoNode  matlab.ui.container.TreeNode
        TnhctchphnbngphngphpSimpson13Node  matlab.ui.container.TreeNode
        TnhctchphnbngphngphpSimpson38Node  matlab.ui.container.TreeNode
        GiaodinNode                    matlab.ui.container.TreeNode
        ThnhvinNode                    matlab.ui.container.TreeNode
        NhimvNode                      matlab.ui.container.TreeNode
        NghimTab                       matlab.ui.container.Tab
        KtqunghimLabel                 matlab.ui.control.Label
        NhphmlpgxLabel                 matlab.ui.control.Label
        aLabel                         matlab.ui.control.Label
        NhphmfxLabel                   matlab.ui.control.Label
        ChnphngphpgiiDropDownLabel     matlab.ui.control.Label
        NhpsaisEditField               matlab.ui.control.NumericEditField
        NhpsaisEditFieldLabel          matlab.ui.control.Label
        SlnlpEditField                 matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        KtqunghimEditField             matlab.ui.control.NumericEditField
        GiiButton                      matlab.ui.control.Button
        NhphmlpgxEditField             matlab.ui.control.EditField
        aEditField                     matlab.ui.control.NumericEditField
        NhphmfxEditField               matlab.ui.control.EditField
        ChnphngphpgiiDropDown          matlab.ui.control.DropDown
        UIAxes_2                       matlab.ui.control.UIAxes
        HiQuyTab                       matlab.ui.container.Tab
        GiatriYTextArea                matlab.ui.control.TextArea
        GitryTextAreaLabel             matlab.ui.control.Label
        GiatriXTextArea                matlab.ui.control.TextArea
        GitrxTextAreaLabel             matlab.ui.control.Label
        GiatriDudoanEditField          matlab.ui.control.NumericEditField
        NhpgitrcndonLabel              matlab.ui.control.Label
        ChonPPhapDropDown              matlab.ui.control.DropDown
        ChnPPhiquyLabel                matlab.ui.control.Label
        KquaDuDoanButton               matlab.ui.control.Button
        PtrinhHoiQuyButton             matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        NiSuyTab                       matlab.ui.container.Tab
        NhpgitrcndonLabel_2            matlab.ui.control.Label
        DliuyLabel                     matlab.ui.control.Label
        DliuxLabel                     matlab.ui.control.Label
        CalculateButton_2              matlab.ui.control.Button
        ValueEditField                 matlab.ui.control.NumericEditField
        ResultTextArea                 matlab.ui.control.TextArea
        LagrangeNewtonDropDown         matlab.ui.control.DropDown
        DataYEditField                 matlab.ui.control.EditField
        DataXEditField                 matlab.ui.control.EditField
        UIAxes2                        matlab.ui.control.UIAxes
        oHmTab                         matlab.ui.container.Tab
        SaiSo                          matlab.ui.control.EditField
        SaisoOhhoacOh2EditFieldLabel   matlab.ui.control.Label
        GiaTriCanDaoHam                matlab.ui.control.NumericEditField
        GiatricanDaohamEditFieldLabel  matlab.ui.control.Label
        Y                              matlab.ui.control.EditField
        YEditFieldLabel                matlab.ui.control.Label
        X                              matlab.ui.control.EditField
        XEditFieldLabel                matlab.ui.control.Label
        HamSO                          matlab.ui.control.EditField
        HamsoEditFieldLabel            matlab.ui.control.Label
        KQ                             matlab.ui.control.NumericEditField
        KetquaEditFieldLabel           matlab.ui.control.Label
        BuocH                          matlab.ui.control.NumericEditField
        BuocHEditFieldLabel            matlab.ui.control.Label
        hoac                           matlab.ui.control.Label
        YeuCau                         matlab.ui.control.Label
        RUN                            matlab.ui.control.Button
        Lui                            matlab.ui.control.Button
        Trungtam                       matlab.ui.control.Button
        Tien                           matlab.ui.control.Button
        TuaInput                       matlab.ui.control.Label
        TuaPhuongPhap                  matlab.ui.control.Label
        TchPhnTab                      matlab.ui.container.Tab
        fxLabel                        matlab.ui.control.Label
        MngYLabel                      matlab.ui.control.Label
        MngXLabel                      matlab.ui.control.Label
        ResultField                    matlab.ui.control.NumericEditField
        KtquEditFieldLabel             matlab.ui.control.Label
        InputN                         matlab.ui.control.NumericEditField
        SonNEditFieldLabel             matlab.ui.control.Label
        InputB                         matlab.ui.control.NumericEditField
        CnbEditFieldLabel              matlab.ui.control.Label
        InputA                         matlab.ui.control.NumericEditField
        CnaEditFieldLabel              matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        InputY                         matlab.ui.control.EditField
        InputX                         matlab.ui.control.EditField
        InputFunction                  matlab.ui.control.EditField
        CalculateButton                matlab.ui.control.Button
        ModeSwitch                     matlab.ui.control.Switch
    end

    
    properties (Access = private)
        PP % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PtrinhHoiQuyButton
        function PtrinhHoiQuyButtonPushed(app, event)
             % str2num đổi chuỗi thành mảng số
    % lấy chuỗi nhập là hàng đầu tiên
        x = str2num(app.GiatriXTextArea.Value{1}); 
        y = str2num(app.GiatriYTextArea.Value{1}); 
    % kiểm tra nếu x,y rỗng
        if isempty(x) || isempty(y)
            uialert(app.UIFigure, 'Chưa nhập dữ liệu x và y', 'Lỗi');
            return;
        end
    % chọn phương pháp từ DropDown và lưu vào biến model.
        method = app.ChonPPhapDropDown.Value;
        model.method = method;  

        switch method
            case 'Tuyến tính'
                p = polyfit(x, y, 1); % polyfit bậc 1 tìm hệ số a,b
                model.a = p(1);
                model.b = p(2);
                yfit = polyval(p, x); % tính giá trị y dự đoán

            case 'Hàm mũ'
     % lấy log 2 vế, dùng polyfit để tìm
                P = polyfit(x, log(y), 1); 
                model.b = P(1);
                model.a = exp(P(2)); % khôi phục a = exp(P(2))
                yfit = model.a * exp(model.b * x);

            case 'Logarit'
                P = polyfit(log(x), y, 1);
                model.b = P(1);
                model.a = P(2);
                yfit = model.a + model.b * log(x);
        end
      % Lưu toàn bộ hệ số hồi quy để khi nhấn nút “Kết quả dự đoán” 
      % có thể dùng lại.
        app.UIFigure.UserData = model;
      % vẽ đồ thị và điểm chính 
        title(app.UIAxes, 'Đồ thị hàm hồi quy và dữ liệu thực');
        plot(app.UIAxes, x, y, 'o', 'LineWidth', 2);
        hold(app.UIAxes, 'on');
        plot(app.UIAxes, x, yfit, 'LineWidth', 2);
        hold(app.UIAxes, 'off');
        legend(app.UIAxes, 'Điểm dữ liệu', 'Hồi quy');
        end

        % Button pushed function: KquaDuDoanButton
        function KquaDuDoanButtonPushed(app, event)
             % lấy giá trị x cần dự đoán
            xp = app.GiatriDudoanEditField.Value;
      % lấy mô hình đã lưu, nếu chưa có model -> lỗi
            model = app.UIFigure.UserData;

            if isempty(model)
                uialert(app.UIFigure, 'Chưa có phương trình hồi quy!', 'Lỗi');
                return;
            end

            a = model.a;
            b = model.b;
            method = model.method;
      % tính y dự đoán
            switch method
                case 'Tuyến tính'
                    ypred = a * xp + b;

                case 'Hàm mũ'
                    ypred = a * exp(b * xp);

                case 'Logarit'
                    ypred = a + b * log(xp);
            end
      % hiện kết quả
            uialert(app.UIFigure, ['Giá trị dự đoán y(x): ' num2str(ypred)], 'Kết quả');
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             % 1. Lấy thông tin đầu vào
    inputMode = app.ModeSwitch.Value; 
    method = app.MethodDropDown.Value; 
    result = 0;
    
    try
        % --- NHÁNH 1: TÍNH TỪ DỮ LIỆU (X, Y) ---
        if strcmpi(inputMode, 'Dữ liệu') || strcmpi(inputMode, 'Data') 
            
            strX = app.InputX.Value;
            strY = app.InputY.Value;
            x = str2num(strX); %#ok<ST2NM>
            y = str2num(strY); %#ok<ST2NM>
            
            % Kiểm tra dữ liệu rỗng
            if isempty(x) || isempty(y)
                uialert(app.UIFigure, 'Vui lòng nhập đầy đủ mảng X và Y!', 'Lỗi nhập liệu');
                return;
            end
            % Kiểm tra độ dài
            if length(x) ~= length(y)
                uialert(app.UIFigure, 'Độ dài mảng X và Y phải bằng nhau!', 'Lỗi kích thước');
                return;
            end
            % Kiểm tra mảng X có tăng dần không
            dx = diff(x);
            if any(dx <= 0)
                uialert(app.UIFigure, 'Mảng X phải tăng dần!', 'Lỗi dữ liệu');
                return;
            end
            % Kiểm tra mảng X có cách đều không (cho phép sai số nhỏ 1e-9)
            if any(abs(dx - dx(1)) > 1e-9)
                uialert(app.UIFigure, 'Các điểm X phải cách đều nhau (h bằng hằng số) mới dùng được phương pháp này!', 'Lỗi dữ liệu');
                return;
            end
            
            h = dx(1);
            n = length(x) - 1; % Số đoạn
            
            % Tính toán theo phương pháp
            switch method
                case 'Hình Thang' % Lưu ý: Chữ T viết hoa để khớp Dropdown
                    result = (h/2) * (y(1) + 2*sum(y(2:end-1)) + y(end));
                    
                case 'Simpson 1/3'
                    if mod(n, 2) ~= 0
                        uialert(app.UIFigure, 'Simpson 1/3 yêu cầu số đoạn (n) phải chẵn.', 'Lỗi thuật toán');
                        return;
                    end
                    even_sum = sum(y(3:2:end-2)); 
                    odd_sum = sum(y(2:2:end-1));  
                    result = (h/3) * (y(1) + 4*odd_sum + 2*even_sum + y(end));
                    
                case 'Simpson 3/8'
                    if mod(n, 3) ~= 0
                        uialert(app.UIFigure, 'Simpson 3/8 yêu cầu số đoạn (n) chia hết cho 3.', 'Lỗi thuật toán');
                        return;
                    end
                    sum_val = y(1) + y(end);
                    for i = 2:n
                        if mod(i-1, 3) == 0
                            sum_val = sum_val + 2 * y(i);
                        else
                            sum_val = sum_val + 3 * y(i);
                        end
                    end
                    result = (3*h/8) * sum_val;
            end
            
        % --- NHÁNH 2: TÍNH TỪ HÀM SỐ f(x) ---
        else 
            funcStr = app.InputFunction.Value;
            if isempty(funcStr)
                    uialert(app.UIFigure, 'Vui lòng nhập hàm số!', 'Lỗi');
                    return;
            end
            
            % Xử lý chuỗi hàm số (thêm chấm . vào các phép toán)
            if startsWith(funcStr, '@')
                f = str2func(funcStr);
            else
                funcStr = strrep(funcStr, '^', '.^');
                funcStr = strrep(funcStr, '*', '.*');
                funcStr = strrep(funcStr, '/', './');
                f = str2func(['@(x) ' funcStr]);
            end
            
            a = app.InputA.Value; 
            b = app.InputB.Value; 
            N = app.InputN.Value; 
            
            if N <= 0
                uialert(app.UIFigure, 'Số đoạn chia N phải lớn hơn 0', 'Lỗi');
                return;
            end
            
            h = (b - a) / N;
            x = a:h:b; 
            y = f(x);  
            
            % Tính toán
            switch method
                case 'Hình Thang'
                    result = (h/2) * (y(1) + 2*sum(y(2:end-1)) + y(end));
                    
                case 'Simpson 1/3'
                    if mod(N, 2) ~= 0
                        uialert(app.UIFigure, 'N phải là số chẵn cho Simpson 1/3', 'Lỗi');
                        return;
                    end
                    even_sum = sum(y(3:2:end-2));
                    odd_sum = sum(y(2:2:end-1));
                    result = (h/3) * (y(1) + 4*odd_sum + 2*even_sum + y(end));
                    
                case 'Simpson 3/8'
                    if mod(N, 3) ~= 0
                        uialert(app.UIFigure, 'N phải chia hết cho 3 cho Simpson 3/8', 'Lỗi');
                        return;
                    end
                    sum_val = y(1) + y(end);
                    for i = 2:N
                        if mod(i-1, 3) == 0
                            sum_val = sum_val + 2 * y(i);
                        else
                            sum_val = sum_val + 3 * y(i);
                        end
                    end
                    result = (3*h/8) * sum_val;
            end
        end
        
        % Hiển thị kết quả
        app.ResultField.Value = result;
        
    catch ex
        uialert(app.UIFigure, ['Lỗi tính toán: ' ex.message], 'Thông báo lỗi');
    end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            mode = app.ModeSwitch.Value;
            if strcmpi(mode, 'Dữ liệu') || strcmpi(mode, 'Data')
        % HIỆN phần Dữ liệu (X, Y)
        app.InputX.Visible = 'on';
        app.NhpmngXEditFieldLabel.Visible = 'on';
        app.InputY.Visible = 'on';
        app.NhpmngYEditFieldLabel.Visible = 'on';
        
        % ẨN phần Hàm số (f(x), a, b, N)
        app.InputFunction.Visible = 'off';
        app.NhphmfxEditFieldLabel.Visible = 'off';
        app.InputA.Visible = 'off';
        app.CnaEditFieldLabel.Visible = 'off';
        app.InputB.Visible = 'off';
        app.CnbEditFieldLabel.Visible = 'off';
        app.InputN.Visible = 'off';
        app.SonNEditFieldLabel.Visible = 'off';
    else
        % ẨN phần Dữ liệu
        app.InputX.Visible = 'off';
        app.NhpmngXEditFieldLabel.Visible = 'off';
        app.InputY.Visible = 'off';
        app.NhpmngYEditFieldLabel.Visible = 'off';
        
        % HIỆN phần Hàm số
        app.InputFunction.Visible = 'on';
        app.NhphmfxEditFieldLabel.Visible = 'on';
        app.InputA.Visible = 'on';
        app.CnaEditFieldLabel.Visible = 'on';
        app.InputB.Visible = 'on';
        app.CnbEditFieldLabel.Visible = 'on';
        app.InputN.Visible = 'on';
        app.SonNEditFieldLabel.Visible = 'on';
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            try
            x = str2num(app.DataXEditField.Value);
            y = str2num(app.DataYEditField.Value);
            x0 = app.ValueEditField.Value; % giá trị cần nội suy\

            if isempty(x) || isempty(y) || length(x) ~= length(y)
                    uialert(app.UIFigure, 'Nhập X và Y có cùng số lượng', 'Lỗi,nhập lại');
                    return;
            end
            method = app.LagrangeNewtonDropDown.Value;
            
            % phần tính toán
                if strcmp(method, 'Lagrange')
                    % Nội suy Lagrange
                    p_sym = LagrangeSym(x, y); % Gọi file LagrangeSym.m
                    y0 = Lagrange(x, y, x0);% Gọi file Lagrange.m
                    
                    % hiển thị dữ liệu
                    polyStr = char(vpa(p_sym, 4));
                    p_coeffs = sym2poly(p_sym);
                    
                elseif strcmp(method, 'Newton')
                    % Nội suy Newton
                    
                    p_sym = NewtonSym(x, y); % Gọi file NewtonSym.m
                    
                    % Tính giá trị số tại x0 (giá trị cần nội suy)
                    y0 = NewtonInterpolation(x, y, x0); % Gọi file NewtonInterpolation.m
                    
                    % hiển thị dữ liệu
                    polyStr = char(vpa(p_sym, 4)); % Đổi đa thức sang chuỗi
                    p_coeffs = sym2poly(p_sym); % Lấy hệ số để vẽ đồ thị
                end
                
                % Hiển thị kết quả ra Text Area
                resultText = sprintf('%s:\n%s\n\nGiá trị nội suy x=%.2f là: %.4f', ...
                                     method, polyStr, x0, y0);
                app.ResultTextArea.Value = resultText;
                
                % Vẽ đồ thị 
                ax = app.UIAxes2;
                cla(ax);  % Xóa đồ thị cũ
                hold(ax, 'on');     
                grid(ax, 'on');     
               
                plot(ax, x, y, 'r.', 'MarkerSize', 15, 'LineWidth', 1.5, 'DisplayName', 'X');
                
                % Vẽ đường cong nội suy
                % Tạo 100 điểm x mịn hơn để vẽ đường cong
                x_plot = linspace(min(x)-1, max(x)+1, 100); 
                y_plot = polyval(p_coeffs, x_plot);
                plot(ax, x_plot, y_plot, 'b-', 'LineWidth', 1.5, 'DisplayName', [method]);
                
                % Vẽ điểm kết quả 
                plot(ax, x0, y0, 'kp', 'MarkerSize', 10, 'MarkerFaceColor', 'g', 'DisplayName', 'Result');
                
                legend(ax, 'Location', 'best');
                hold(ax, 'off');
                
            catch ME
                % Bắt lỗi nếu thiếu file hoặc tính toán sai
                uialert(app.UIFigure, ['Lỗi: ' ME.message], 'Thông báo lỗi');
            end
        end

        % Button pushed function: Tien
        function TienButtonPushed(app, event)
               app.PP = 'tien';
        end

        % Button pushed function: Trungtam
        function TrungtamButtonPushed(app, event)
               app.PP = 'trungtam';
        end

        % Button pushed function: Lui
        function LuiButtonPushed(app, event)
            app.PP = 'lui';
        end

        % Button pushed function: RUN
        function RUNButtonPushed(app, event)
            if isempty(app.PP)
                uialert(app.UIFigure,'Chua chon phuong phap','Loi');
                return
            end


            if ~isempty(strtrim(app.HamSO.Value))

                fstr = app.HamSO.Value;
                h    = app.BuocH.Value;
                x0   = app.GiaTriCanDaoHam.Value;

                try
                    f = str2func(['@(x)', fstr]);
                catch
                    uialert(app.UIFigure,'Ham so khong hop le','Loi');
                    return
                end

                s = lower(strtrim(app.SaiSo.Value));
                if contains(s,'h^2')
                    order = 2;
                else
                    order = 1;
                end

                switch app.PP
                    case 'tien'
                        if order == 1
                            kq = (f(x0+h) - f(x0)) / h;
                        else
                            kq = (-3*f(x0) + 4*f(x0+h) - f(x0+2*h)) / (2*h);
                        end

                    case 'lui'
                        if order == 1
                            kq = (f(x0) - f(x0-h)) / h;
                        else
                            kq = (3*f(x0) - 4*f(x0-h) + f(x0-2*h)) / (2*h);
                        end

                    case 'trungtam'
                        kq = (f(x0+h) - f(x0-h)) / (2*h);
                end

                app.KQ.Value = kq;
                return
            end


            try
                X = str2num(app.X.Value); %#ok<ST2NM>
                Y = str2num(app.Y.Value);
            catch
                uialert(app.UIFigure,'Nhap X, Y dang [1 2 3]','Loi');
                return
            end

            if isempty(X) || isempty(Y) || length(X) ~= length(Y)
                uialert(app.UIFigure,'X va Y phai cung kich thuoc','Loi');
                return
            end

            x0 = app.GiaTriCanDaoHam.Value;
            idx = find(X == x0);

            if isempty(idx)
                uialert(app.UIFigure,'Gia tri can dao ham khong nam trong X','Loi');
                return
            end

            h = X(2) - X(1);   % giả sử bước đều

            switch app.PP
                case 'tien'
                    if idx == length(X)
                        uialert(app.UIFigure,'Khong du diem cho sai phan tien','Loi');
                        return
                    end
                    kq = (Y(idx+1) - Y(idx)) / h;

                case 'lui'
                    if idx == 1
                        uialert(app.UIFigure,'Khong du diem cho sai phan lui','Loi');
                        return
                    end
                    kq = (Y(idx) - Y(idx-1)) / h;

                case 'trungtam'
                    if idx == 1 || idx == length(X)
                        uialert(app.UIFigure,'Khong du diem cho sai phan trung tam','Loi');
                        return
                    end
                    kq = (Y(idx+1) - Y(idx-1)) / (2*h);
            end

            app.KQ.Value = kq;
        end

        % Button pushed function: GiiButton
        function GiiButtonPushed(app, event)
             %Lấy dữ liệu nhập vào
            try
                fx = str2func(['@(x)' app.NhphmfxEditField.Value]);
                saiso = app.NhpsaisEditField.Value;
                a = app.aEditField.Value;
                if strcmp(app.ChnphngphpgiiDropDown.Value,'Chia đôi')
                    b = app.bEditField.Value;
                end
            catch
                uialert(app.UIFigure,'Lỗi cú pháp hàm hoặc dữ liệu nhập!','Lỗi');
                return;
            end

            % Chọn phương pháp
            try
                switch app.ChnphngphpgiiDropDown.Value

                    case 'Chia đôi'
                        fa = fx(a); fb = fx(b);
                        if fa*fb > 0
                            uialert(app.UIFigure, ...
                                'Chọn lại khoảng có f(a)*f(b) < 0 !', 'Lỗi');
                            return;
                        end
                        [result, n] = app.chiadoi(fx, a, b, saiso);

                    case 'Lặp'
                        g = str2func(['@(x)' app.NhphmlpgxEditField.Value]);
                        [result, n] = app.lap(g, a, saiso);

                    case 'Newton'
                        df = str2func(['@(x)' app.NhphmlpgxEditField.Value]);
                        [result, n] = app.newton(fx, df, a, saiso);
                end

            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi thuật toán');
                return;
            end


            % Hiển thị kết quả
            app.KtqunghimEditField.Value = result;
            app.SlnlpEditField.Value = n;

            % Vẽ đồ thị 
            cla(app.UIAxes_2);
            fplot(app.UIAxes_2, fx);
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2, result, fx(result),'ro','MarkerSize',8,'LineWidth',2);
            hold(app.UIAxes_2,'off');
            grid(app.UIAxes_2,'on');
            title(app.UIAxes_2,'Đồ thị f(x)');
            xlabel(app.UIAxes_2,'x'); ylabel(app.UIAxes_2,'f(x)');
        end
        end
            % 
    % HÀM THUẬT TOÁN (STATIC METHODS)
    % 
    methods (Static)

        % Chia đôi
        function [result, n] = chiadoi(f, a, b, eps)
            n = 0;
            while abs(b - a) > eps
                c = (a + b)/2;
                if f(a)*f(c) < 0
                    b = c;
                else
                    a = c;
                end
                n = n + 1;
            end
            result = (a + b)/2;
        end

        %  Lặp 
        function [result, n] = lap(g, x0, eps)
            n = 0;
            while true
                x1 = g(x0);
                n = n + 1;
                if abs(x1 - x0) < eps
                    result = x1;
                    return;
                end
                x0 = x1;
            end
        end

        % Newton
        function [result, n] = newton(f, df, x0, eps)
            n = 0;
            while true
                x1 = x0 - f(x0)/df(x0);
                n = n + 1;
                if abs(x1 - x0) < eps
                    result = x1;
                    return;
                end
                x0 = x1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NhmTab = uitab(app.TabGroup);
            app.NhmTab.Title = 'Nhóm';
            app.TextArea = uitextarea(app.NhmTab);
            app.TextArea.Value = {'GIỚI THIỆU'};
            app.TextArea_2 = uitextarea(app.NhmTab);
            app.TextArea_2.Value = {'Đề tài: Ứng dụng Matlab App Designer trong việc mô phỏng và giải quyết các bài toán Phương pháp tính. '; ''; ''; 'Ứng dụng được thiết kế chuyên nghiệp với hệ thống đa Tab, trong đó mỗi Tab là một chủ đề toán học riêng biệt giúp người dùng dễ dàng chuyển đổi và thao tác. '; ''; 'Ứng dụng triển khai trọn vẹn 5 phân mục trọng tâm bao gồm: Tìm nghiệm phương trình, Nội suy, Hồi quy, tính Đạo hàm và Tích phân số theo đúng tiêu chí đánh giá chuyên môn.'};
            app.ThnhvinTab = uitab(app.TabGroup);
            app.ThnhvinTab.Title = 'Thành viên';
            app.TextArea_3 = uitextarea(app.ThnhvinTab);
            app.TextArea_3.Value = {'Mai Lý Khải Triều '; '23207136 '; '23DTV_CLC2'};
            app.TextArea_4 = uitextarea(app.ThnhvinTab);
            app.TextArea_4.Value = {'Bùi Anh Phúc '; '23207094'; '23DTV_CLC2'};
            app.TextArea_5 = uitextarea(app.ThnhvinTab);
            app.TextArea_5.Value = {'Đỗ Trường Tín '; '23207135 '; '23DTV_CLC2'};
            app.TextArea_6 = uitextarea(app.ThnhvinTab);
            app.TextArea_6.Value = {'Lý Anh Thư '; '23207114'; '23DTV_CLC2'};
            app.TextArea_7 = uitextarea(app.ThnhvinTab);
            app.TextArea_7.Value = {'Hà Vĩ Khang'; '23207131'; '23DTV_CLC3'};
            app.TextArea_8 = uitextarea(app.ThnhvinTab);
            app.TextArea_8.Value = {'Phan Phước Sơn'; '23207101'; '23DTV_CLC2 '};
            app.NhimvTab = uitab(app.TabGroup);
            app.NhimvTab.Title = 'Nhiệm vụ';
            app.Tree = uitree(app.NhimvTab);
            app.TmnghimNode = uitreenode(app.Tree);
            app.TmnghimNode.Text = 'Tìm nghiệm';
            app.ChiaiNode = uitreenode(app.TmnghimNode);
            app.ChiaiNode.Text = 'Chia đôi';
            app.LpNode = uitreenode(app.TmnghimNode);
            app.LpNode.Text = 'Lặp';
            app.NewtonNode = uitreenode(app.TmnghimNode);
            app.NewtonNode.Text = 'Newton';
            app.HiquyNode = uitreenode(app.Tree);
            app.HiquyNode.Text = 'Hồi quy';
            app.TuyntnhNode = uitreenode(app.HiquyNode);
            app.TuyntnhNode.Text = 'Tuyến tính';
            app.HmmNode = uitreenode(app.HiquyNode);
            app.HmmNode.Text = 'Hàm mũ';
            app.MeNode = uitreenode(app.HiquyNode);
            app.MeNode.Text = 'Mũ e';
            app.NisuyNode = uitreenode(app.Tree);
            app.NisuyNode.Text = 'Nội suy';
            app.NisuyNewtonNode = uitreenode(app.NisuyNode);
            app.NisuyNewtonNode.Text = 'Nội suy Newton';
            app.NisuyLagrangeNode = uitreenode(app.NisuyNode);
            app.NisuyLagrangeNode.Text = 'Nội suy Lagrange';
            app.ohmNode = uitreenode(app.Tree);
            app.ohmNode.Text = 'Đạo hàm';
            app.ohmtdliuxyNode = uitreenode(app.ohmNode);
            app.ohmtdliuxyNode.Text = 'Đạo hàm từ dữ liệu x,y';
            app.ohmthmsNode = uitreenode(app.ohmNode);
            app.ohmthmsNode.Text = 'Đạo hàm từ hàm số';
            app.ThayicphngphptnhohmXpxtinxpxlixpxtrungtmNode = uitreenode(app.ohmNode);
            app.ThayicphngphptnhohmXpxtinxpxlixpxtrungtmNode.Text = 'Thay đổi được phương pháp tính đạo hàm: Xấp xỉ tiến, xấp xỉ lùi, xấp xỉ trung tâm';
            app.TchphnNode = uitreenode(app.Tree);
            app.TchphnNode.Text = 'Tích phân';
            app.TnhctchphnhnhthangtxyNode = uitreenode(app.TchphnNode);
            app.TnhctchphnhnhthangtxyNode.Text = 'Tính được tích phân hình thang từ x,y';
            app.TnhctchphnhnhthangthmsnhpvoNode = uitreenode(app.TchphnNode);
            app.TnhctchphnhnhthangthmsnhpvoNode.Text = 'Tính được tích phân hình thang từ hàm số nhập vào';
            app.TnhctchphnbngphngphpSimpson13Node = uitreenode(app.TchphnNode);
            app.TnhctchphnbngphngphpSimpson13Node.Text = 'Tính được tích phân bằng phương pháp Simpson 1/3';
            app.TnhctchphnbngphngphpSimpson38Node = uitreenode(app.TchphnNode);
            app.TnhctchphnbngphngphpSimpson38Node.Text = 'Tính được tích phân bằng phương pháp Simpson 3/8';
            app.GiaodinNode = uitreenode(app.Tree);
            app.GiaodinNode.Text = 'Giao diện';
            app.ThnhvinNode = uitreenode(app.GiaodinNode);
            app.ThnhvinNode.Text = 'Thành viên';
            app.NhimvNode = uitreenode(app.GiaodinNode);
            app.NhimvNode.Text = 'Nhiệm vụ';
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = '1. Nghiệm';
            app.UIAxes_2 = uiaxes(app.NghimTab);
            title(app.UIAxes_2, 'Đồ thị hàm số')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ChnphngphpgiiDropDown = uidropdown(app.NghimTab);
            app.ChnphngphpgiiDropDown.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.ChnphngphpgiiDropDown.Value = 'Chia đôi';
            app.NhphmfxEditField = uieditfield(app.NghimTab, 'text');
            app.aEditField = uieditfield(app.NghimTab, 'numeric');
            app.NhphmlpgxEditField = uieditfield(app.NghimTab, 'text');
            app.GiiButton = uibutton(app.NghimTab, 'push');
            app.GiiButton.ButtonPushedFcn = createCallbackFcn(app, @GiiButtonPushed, true);
            app.GiiButton.Text = 'Giải';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.NghimTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.NhpsaisEditFieldLabel = uilabel(app.NghimTab);
            app.NhpsaisEditFieldLabel.Text = 'Nhập sai số';
            app.NhpsaisEditField = uieditfield(app.NghimTab, 'numeric');
            app.ChnphngphpgiiDropDownLabel = uilabel(app.NghimTab);
            app.ChnphngphpgiiDropDownLabel.Text = 'Chọn phương pháp giải';
            app.NhphmfxLabel = uilabel(app.NghimTab);
            app.NhphmfxLabel.Text = 'Nhập hàm f(x)';
            app.aLabel = uilabel(app.NghimTab);
            app.aLabel.Text = 'a';
            app.NhphmlpgxLabel = uilabel(app.NghimTab);
            app.NhphmlpgxLabel.Text = 'Nhập hàm lặp g(x)';
            app.KtqunghimLabel = uilabel(app.NghimTab);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm';
            app.HiQuyTab = uitab(app.TabGroup);
            app.HiQuyTab.Title = '2. Hồi Quy';
            app.UIAxes = uiaxes(app.HiQuyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PtrinhHoiQuyButton = uibutton(app.HiQuyTab, 'push');
            app.PtrinhHoiQuyButton.ButtonPushedFcn = createCallbackFcn(app, @PtrinhHoiQuyButtonPushed, true);
            app.PtrinhHoiQuyButton.Text = 'Kết quả phương trình hồi quy';
            app.KquaDuDoanButton = uibutton(app.HiQuyTab, 'push');
            app.KquaDuDoanButton.ButtonPushedFcn = createCallbackFcn(app, @KquaDuDoanButtonPushed, true);
            app.KquaDuDoanButton.Text = 'Kết quả dự đoán';
            app.ChnPPhiquyLabel = uilabel(app.HiQuyTab);
            app.ChnPPhiquyLabel.Text = {'Chọn PP '; 'hồi quy'};
            app.ChonPPhapDropDown = uidropdown(app.HiQuyTab);
            app.ChonPPhapDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChonPPhapDropDown.Value = 'Tuyến tính';
            app.NhpgitrcndonLabel = uilabel(app.HiQuyTab);
            app.NhpgitrcndonLabel.Text = {'Nhập giá trị '; 'cần dự đoán'};
            app.GiatriDudoanEditField = uieditfield(app.HiQuyTab, 'numeric');
            app.GitrxTextAreaLabel = uilabel(app.HiQuyTab);
            app.GitrxTextAreaLabel.Text = 'Giá trị x:';
            app.GiatriXTextArea = uitextarea(app.HiQuyTab);
            app.GitryTextAreaLabel = uilabel(app.HiQuyTab);
            app.GitryTextAreaLabel.Text = 'Giá trị y:';
            app.GiatriYTextArea = uitextarea(app.HiQuyTab);
            app.NiSuyTab = uitab(app.TabGroup);
            app.NiSuyTab.Title = '3. Nội Suy';
            app.UIAxes2 = uiaxes(app.NiSuyTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.DataXEditField = uieditfield(app.NiSuyTab, 'text');
            app.DataYEditField = uieditfield(app.NiSuyTab, 'text');
            app.LagrangeNewtonDropDown = uidropdown(app.NiSuyTab);
            app.LagrangeNewtonDropDown.Items = {'Lagrange', 'Newton', ''};
            app.LagrangeNewtonDropDown.Value = 'Lagrange';
            app.ResultTextArea = uitextarea(app.NiSuyTab);
            app.ValueEditField = uieditfield(app.NiSuyTab, 'numeric');
            app.CalculateButton_2 = uibutton(app.NiSuyTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Kểt quả nội suy';
            app.DliuxLabel = uilabel(app.NiSuyTab);
            app.DliuxLabel.Text = 'Dữ liệu x';
            app.DliuyLabel = uilabel(app.NiSuyTab);
            app.DliuyLabel.Text = 'Dữ liệu y';
            app.NhpgitrcndonLabel_2 = uilabel(app.NiSuyTab);
            app.NhpgitrcndonLabel_2.Text = 'Nhập giá trị cần dự đoán';
            app.oHmTab = uitab(app.TabGroup);
            app.oHmTab.Title = '4. Đạo Hàm';
            app.TuaPhuongPhap = uilabel(app.oHmTab);
            app.TuaPhuongPhap.Text = '2.Chọn phương pháp tính Đạo hàm';
            app.TuaInput = uilabel(app.oHmTab);
            app.TuaInput.Text = '1.Dữ liệu đầu vào Input';
            app.Tien = uibutton(app.oHmTab, 'push');
            app.Tien.ButtonPushedFcn = createCallbackFcn(app, @TienButtonPushed, true);
            app.Tien.Text = 'Xap xi Tien';
            app.Trungtam = uibutton(app.oHmTab, 'push');
            app.Trungtam.ButtonPushedFcn = createCallbackFcn(app, @TrungtamButtonPushed, true);
            app.Trungtam.Text = 'Xap xi Trung tam';
            app.Lui = uibutton(app.oHmTab, 'push');
            app.Lui.ButtonPushedFcn = createCallbackFcn(app, @LuiButtonPushed, true);
            app.Lui.Text = 'Xap xi Lui';
            app.RUN = uibutton(app.oHmTab, 'push');
            app.RUN.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUN.Text = 'RUN';
            app.YeuCau = uilabel(app.oHmTab);
            app.YeuCau.Text = '3. Yeu cau';
            app.hoac = uilabel(app.oHmTab);
            app.hoac.Text = 'hoac';
            app.BuocHEditFieldLabel = uilabel(app.oHmTab);
            app.BuocHEditFieldLabel.Text = 'Buoc H';
            app.BuocH = uieditfield(app.oHmTab, 'numeric');
            app.KetquaEditFieldLabel = uilabel(app.oHmTab);
            app.KetquaEditFieldLabel.Text = 'Ket qua';
            app.KQ = uieditfield(app.oHmTab, 'numeric');
            app.HamsoEditFieldLabel = uilabel(app.oHmTab);
            app.HamsoEditFieldLabel.Text = 'Ham so';
            app.HamSO = uieditfield(app.oHmTab, 'text');
            app.XEditFieldLabel = uilabel(app.oHmTab);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.oHmTab, 'text');
            app.YEditFieldLabel = uilabel(app.oHmTab);
            app.YEditFieldLabel.Text = 'Y';
            app.Y = uieditfield(app.oHmTab, 'text');
            app.GiatricanDaohamEditFieldLabel = uilabel(app.oHmTab);
            app.GiatricanDaohamEditFieldLabel.Text = 'Gia tri can Dao ham';
            app.GiaTriCanDaoHam = uieditfield(app.oHmTab, 'numeric');
            app.SaisoOhhoacOh2EditFieldLabel = uilabel(app.oHmTab);
            app.SaisoOhhoacOh2EditFieldLabel.Text = 'Sai so O(h) hoac O(h^2)';
            app.SaiSo = uieditfield(app.oHmTab, 'text');
            app.TchPhnTab = uitab(app.TabGroup);
            app.TchPhnTab.Title = '5. Tích Phân';
            app.ModeSwitch = uiswitch(app.TchPhnTab, 'slider');
            app.ModeSwitch.Items = {'Dữ Liệu', 'Hàm số'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Dữ Liệu';
            app.CalculateButton = uibutton(app.TchPhnTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Tính kết quả';
            app.InputFunction = uieditfield(app.TchPhnTab, 'text');
            app.InputX = uieditfield(app.TchPhnTab, 'text');
            app.InputY = uieditfield(app.TchPhnTab, 'text');
            app.DropDownLabel = uilabel(app.TchPhnTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.MethodDropDown = uidropdown(app.TchPhnTab);
            app.MethodDropDown.Items = {'Hình Thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.MethodDropDown.Value = 'Hình Thang';
            app.CnaEditFieldLabel = uilabel(app.TchPhnTab);
            app.CnaEditFieldLabel.Text = 'Cận a';
            app.InputA = uieditfield(app.TchPhnTab, 'numeric');
            app.CnbEditFieldLabel = uilabel(app.TchPhnTab);
            app.CnbEditFieldLabel.Text = 'Cận b';
            app.InputB = uieditfield(app.TchPhnTab, 'numeric');
            app.SonNEditFieldLabel = uilabel(app.TchPhnTab);
            app.SonNEditFieldLabel.Text = 'Số đoạn N';
            app.InputN = uieditfield(app.TchPhnTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.TchPhnTab);
            app.KtquEditFieldLabel.Text = 'Kết quả:';
            app.ResultField = uieditfield(app.TchPhnTab, 'numeric');
            app.MngXLabel = uilabel(app.TchPhnTab);
            app.MngXLabel.Text = 'Mảng X';
            app.MngYLabel = uilabel(app.TchPhnTab);
            app.MngYLabel.Text = 'Mảng Y';
            app.fxLabel = uilabel(app.TchPhnTab);
            app.fxLabel.Text = 'f(x)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAn_THPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end